classdef app2prePorcessData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        FOGSwitch                matlab.ui.control.Switch
        FOGSwitchLabel           matlab.ui.control.Label
        exportButton             matlab.ui.control.Button
        importButton             matlab.ui.control.Button
        applyButton              matlab.ui.control.Button
        splittoEditField         matlab.ui.control.NumericEditField
        splittoEditFieldLabel    matlab.ui.control.Label
        splitfromEditField       matlab.ui.control.NumericEditField
        splitfromEditFieldLabel  matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        data_file % imported data file
        data_file_path % path of the imported data
        data_file_name

        data_array % just all values

        splitfrom
        splitto

        data_cut

        isfog = false; %bool if fog or not
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            [fname, fpath] = uigetfile('*.txt'); 
            app.data_file_name = fname;
            app.data_file_path = fpath;
            app.data_file = importfile(append(fpath, fname));


            %% moving measurment data to data_array
            %disp("moving all values to data_array...");
            data_array = app.data_file(5:end); % move all m_values to data_array

            %disp("erasing all semicolons...");
            data_array = erase(data_array, ";"); %erasing ";"

            %disp("splitting values...");
            data_array = split(data_array, ","); %spliting at ","

            %disp("converting values to double...");

            %%
    fig = uifigure;
    d = uiprogressdlg(fig,'Title','dOiNg sTuFf',...
        'Indeterminate','on');
    drawnow
    
    % Do the SVD computation
    %svd(rand(5000));

            %%

            app.data_array = str2double(data_array); %converting to double values


    % close the dialog box
    close(d)
    close(fig)

app.splittoEditField.Value = size(app.data_array, 1);
app.splitto = app.splittoEditField.Value;

            plot(app.UIAxes, app.data_array(:,2:end))

        end

        % Value changed function: splitfromEditField
        function splitfromEditFieldValueChanged(app, event)
            app.splitfrom = app.splitfromEditField.Value;


            %%
            s_start = app.splitfrom;
            s_stopp = app.splitto;

            if isempty(app.splitfrom)
                s_start = 1;
            end

            if s_start > s_stopp
                s_start = app.splitto;
                s_stopp = app.splitfrom;
            end

            app.data_cut = app.data_array(s_start:s_stopp, :);

            plot(app.UIAxes, app.data_cut(:,2:end))

        end

        % Value changed function: splittoEditField
        function splittoEditFieldValueChanged(app, event)
            app.splitto = app.splittoEditField.Value;

            if app.splittoEditField.Value > size(app.data_array, 1)
                app.splittoEditField.Value = size(app.data_array, 1);
                app.splitto = app.splittoEditField.Value;
            end


%%
            s_start = app.splitfrom;
            s_stopp = app.splitto;

            if isempty(app.splitfrom)
                s_start = 1;
            end

            if s_start > s_stopp
                s_start = app.splitto;
                s_stopp = app.splitfrom;
            end

            app.data_cut = app.data_array(s_start:s_stopp, :);

            plot(app.UIAxes, app.data_cut(:,2:end))
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            s_start = app.splitfrom;
            s_stopp = app.splitto;

            if isempty(app.splitfrom)
                s_start = 1;
            end

            if isempty(app.splitto)
                s_stopp = 100;
            end

            if s_start > s_stopp
                s_start = app.splitto;
                s_stopp = app.splitfrom;
            end

            app.data_cut = app.data_array(s_start:s_stopp, :);

            plot(app.UIAxes, app.data_cut(:,2:end))
        end

        % Value changed function: FOGSwitch
        function FOGSwitchValueChanged(app, event)

        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
            if app.FOGSwitch.Value == 1;
                filename = append('fog_', app.data_file_name);
            else
                filename = append('notfog_', app.data_file_name);
            end


%% time

%disp("changing time to start from zero....");
app.data_cut(:,1) = app.data_cut(:,1) - app.data_cut(1,1); 
%time start is zero
app.data_cut(:,1) = app.data_cut(:,1)/1000; %time display in seconds

app.data_cut(:,2:end) = app.data_cut(:,2:end) / 100;
%%


            filename = filename(1:end-3);
            filename = append(filename, 'csv');
            
            [ex_file, ex_path] = uiputfile(filename);
            writematrix(app.data_cut, append(ex_path, ex_file))
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.splitfromEditFieldLabel = uilabel(app.LeftPanel);
            app.splitfromEditFieldLabel.Text = 'split from';
            app.splitfromEditField = uieditfield(app.LeftPanel, 'numeric');
            app.splitfromEditField.Limits = [1 Inf];
            app.splitfromEditField.ValueDisplayFormat = '%.0f';
            app.splitfromEditField.ValueChangedFcn = createCallbackFcn(app, @splitfromEditFieldValueChanged, true);
            app.splitfromEditField.Value = 1;
            app.splittoEditFieldLabel = uilabel(app.LeftPanel);
            app.splittoEditFieldLabel.Text = 'split to';
            app.splittoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.splittoEditField.Limits = [1 Inf];
            app.splittoEditField.ValueDisplayFormat = '%.0f';
            app.splittoEditField.ValueChangedFcn = createCallbackFcn(app, @splittoEditFieldValueChanged, true);
            app.splittoEditField.Value = 1;
            app.applyButton = uibutton(app.LeftPanel, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Text = 'apply';
            app.importButton = uibutton(app.LeftPanel, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import';
            app.exportButton = uibutton(app.LeftPanel, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'export';
            app.FOGSwitchLabel = uilabel(app.LeftPanel);
            app.FOGSwitchLabel.Text = 'FOG';
            app.FOGSwitch = uiswitch(app.LeftPanel, 'slider');
            app.FOGSwitch.Items = {'false', 'true'};
            app.FOGSwitch.ValueChangedFcn = createCallbackFcn(app, @FOGSwitchValueChanged, true);
            app.FOGSwitch.Value = 'false';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'DaTa')
            xlabel(app.UIAxes, 'X vAlUeS')
            ylabel(app.UIAxes, 'Y vAlUeS')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2prePorcessData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end