classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        disconnectButton      matlab.ui.control.Button
        Lamp                  matlab.ui.control.Lamp
        xrangeEditField       matlab.ui.control.NumericEditField
        xrangeEditFieldLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        displaypannelPanel    matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        s4_d_CheckBox         matlab.ui.control.CheckBox
        s3_d_CheckBox         matlab.ui.control.CheckBox
        s2_d_CheckBox         matlab.ui.control.CheckBox
        s1_d_CheckBox         matlab.ui.control.CheckBox
        z_d_CheckBox          matlab.ui.control.CheckBox
        y_d_CheckBox          matlab.ui.control.CheckBox
        x_d_CheckBox          matlab.ui.control.CheckBox
        grav_d_CheckBox       matlab.ui.control.CheckBox
        acc_d_CheckBox        matlab.ui.control.CheckBox
        mag_d_CheckBox        matlab.ui.control.CheckBox
        linacc_d_CheckBox     matlab.ui.control.CheckBox
        gyro_d_CheckBox       matlab.ui.control.CheckBox
        ori_d_CheckBox        matlab.ui.control.CheckBox
        connectButton         matlab.ui.control.Button
        setuppannelPanel      matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        s4_s_CheckBox         matlab.ui.control.CheckBox
        s3_s_CheckBox         matlab.ui.control.CheckBox
        s2_s_CheckBox         matlab.ui.control.CheckBox
        s1_s_CheckBox         matlab.ui.control.CheckBox
        grav_s_CheckBox       matlab.ui.control.CheckBox
        acc_s_CheckBox        matlab.ui.control.CheckBox
        mag_s_CheckBox        matlab.ui.control.CheckBox
        linacc_s_CheckBox     matlab.ui.control.CheckBox
        gyro_s_CheckBox       matlab.ui.control.CheckBox
        ori_s_CheckBox        matlab.ui.control.CheckBox
        RightPanel            matlab.ui.container.Panel
        UIAxes_down           matlab.ui.control.UIAxes
        UIAxes_up             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % connection variables
        ble_device          % BLE device id
        ble_c               % BLE characteristic
        
        draw_plot = false;           % bool if true starts plotting values

        %received data
        r_data                % stores all the data received by ble
        r_data_selected         %stores all in display pannel selected data
        r_data_dval         % received data changed to double values
        r_data_norm         % received data changed normalized values

        % display variables
        d_ori   = false;             % orientation data
        d_gyro  = false;            % gyroscope data
        d_linacc  = false;          % linear acceleration
        d_mag     = false;          % magnetometer
        d_acc   = false;            % acceleration
        d_grav  = false;            % gravitation

        d_sensor1  = false;         % sensor bool for checkbox and plot
        d_sensor2  = false;
        d_sensor3  = false;
        d_sensor4  = false;

        d_x = false;
        d_y = false;
        d_z = false;

        %% setup variables
        % measurmentt type
        s_ori = false;               % bool variables for selection of setup
        s_gyro = false;
        s_linacc = false;
        s_mag = false;
        s_acc = false;
        s_grav = false;

        % sensors
        s_sensor1  = false;     % bool variables for selection of setup
        s_sensor2 = false;
        s_sensor3 = false;
        s_sensor4 = false;

        % plot variables
        plot_xlimit             % varable for plot range (zero to x_limit)
        plot_counter_index             % counter variable for data array
        buff                    %buffer variable to read the ble string

        p_sensor_counter = 0;        %counts how many sensors are selected
        p_mtyoes_counter = 0;         %counts how many measurment types are selected
        p_index             %stores all the indexes to plot

        indexer             %hold index value for setup of 'i_ori... ' vars
        i_ori               %stores the indexes of mtype per sensor
        i_gyro
        i_linacc
        i_mag
        i_acc
        i_grav

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
            % % clearing variables befor reloading information
            % clear app.ble_device;
            % clear app.ble_c;
            
            %% setting up ble connection
            if(app.s_sensor1 || app.s_sensor2 || app.s_sensor3 || app.s_sensor4)
                if(app.s_ori || app.s_gyro || app.s_linacc || app.s_mag || app.s_acc || app.s_grav)
                    
                    % connectin to device
                    app.ble_device = "";                                            % if there is a connection disconnect
                    app.ble_device = ble("FOG Nano");                               % connect to ble device
                    app.ble_c = characteristic(app.ble_device, "180F", "2A19");     % get characteristics
                    subscribe(app.ble_c);

                    % setting draw_plot
                    app.draw_plot = true;                                           % bool to start the plot loop
                    
                    % indicator lamp
                    app.Lamp.Color = 'green';
                end
            end

            %% indication light if nothing choosen from setup pannel 
            if(not(app.s_sensor1 || app.s_sensor2 || app.s_sensor3 || app.s_sensor4))
                if(not(app.s_ori || app.s_gyro || app.s_linacc || app.s_mag || app.s_acc || app.s_grav))
                    app.Lamp.Color = 'yellow';
                end
            end

            
            %% starting the plot
            if(app.s_sensor1 || app.s_sensor2 || app.s_sensor3 || app.s_sensor4)
                if(app.s_ori || app.s_gyro || app.s_linacc || app.s_mag || app.s_acc || app.s_grav)

                    % if value is not initialized 
                    if isempty(app.plot_counter_index)
                        app.plot_counter_index = 1;
                    end
                    
                    %count sensors
                    app.p_sensor_counter = 0;
                    if app.s1_s_CheckBox.Value
                        app.p_sensor_counter = app.p_sensor_counter + 1;
                    end
                    if app.s2_s_CheckBox.Value
                        app.p_sensor_counter = app.p_sensor_counter + 1;
                    end
                    if app.s3_s_CheckBox.Value
                        app.p_sensor_counter = app.p_sensor_counter + 1;
                    end
                    if app.s4_s_CheckBox.Value
                        app.p_sensor_counter = app.p_sensor_counter + 1;
                    end

                    %count measurment types
                    app.p_mtyoes_counter = 0;
                    %1
                    if app.ori_s_CheckBox.Value
                        app.p_mtyoes_counter = app.p_mtyoes_counter + 1;
                    end
                    %2
                    if app.gyro_s_CheckBox.Value
                        app.p_mtyoes_counter = app.p_mtyoes_counter + 1;
                    end
                    %3
                    if app.linacc_s_CheckBox.Value
                        app.p_mtyoes_counter = app.p_mtyoes_counter + 1;
                    end                    
                    %4
                    if app.mag_s_CheckBox.Value
                        app.p_mtyoes_counter = app.p_mtyoes_counter + 1;
                    end
                    %5
                    if app.acc_s_CheckBox.Value
                        app.p_mtyoes_counter = app.p_mtyoes_counter + 1;
                    end
                    %6
                    if app.grav_s_CheckBox.Value
                        app.p_mtyoes_counter = app.p_mtyoes_counter + 1;
                    end               

%                     %d_matrix = zeros(app.p_sensor_counter, app.p_mtypes_counter);
%                     %filling the display matrix with indixes
%                     for c = 1:app.p_sensor_counter)
%                         for cc = 1:app.p_mtyoes_counter
%                             app.d_matrix(c, cc) = cc * (c - 1);
%                         end
%                     end

                    %% setup i_mtypes variables for indexes
                    app.indexer = 1; % setting indexer to one for start

                    if app.s_sensor1
                        if app.s_ori
                            app.i_ori = vertcat(app.i_ori, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_gyro
                            app.i_gyro = vertcat(app.i_gyro, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_linacc
                            app.i_linacc = vertcat(app.i_linacc, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_mag
                            app.i_mag = vertcat(app.i_mag, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_acc
                            app.i_acc = vertcat(app.i_acc, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_grav
                            app.i_grav = vertcat(app.i_grav, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                    end

                    if app.s_sensor2
                        if app.s_ori
                            app.i_ori = vertcat(app.i_ori, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_gyro
                            app.i_gyro = vertcat(app.i_gyro, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_linacc
                            app.i_linacc = vertcat(app.i_linacc, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_mag
                            app.i_mag = vertcat(app.i_mag, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_acc
                            app.i_acc = vertcat(app.i_acc, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_grav
                            app.i_grav = vertcat(app.i_grav, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                    end

                    if app.s_sensor3
                        if app.s_ori
                            app.i_ori = vertcat(app.i_ori, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_gyro
                            app.i_gyro = vertcat(app.i_gyro, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_linacc
                            app.i_linacc = vertcat(app.i_linacc, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_mag
                            app.i_mag = vertcat(app.i_mag, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_acc
                            app.i_acc = vertcat(app.i_acc, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_grav
                            app.i_grav = vertcat(app.i_grav, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                    end

                    if app.s_sensor4
                        if app.s_ori
                            app.i_ori = vertcat(app.i_ori, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_gyro
                            app.i_gyro = vertcat(app.i_gyro, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_linacc
                            app.i_linacc = vertcat(app.i_linacc, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_mag
                            app.i_mag = vertcat(app.i_mag, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_acc
                            app.i_acc = vertcat(app.i_acc, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                        if app.s_grav
                            app.i_grav = vertcat(app.i_grav, [app.indexer, app.indexer+1, app.indexer+2]);
                            app.indexer = app.indexer + 3;
                        end
                    end


                    %% continues drawing plot till draw_plot is set to false
                    while app.draw_plot
                        tic
                    
                        %receiving data from ble 
                        app.buff = str2num(char(read(app.ble_c)));
                        app.buff = app.buff / 100;  %presicion value
                        %values are multiplied by 100 before transfere;
                        app.buff = app.buff(2:end); %removing time (first value)

                        %d_matrix = [] %display matrix stores the indixes
                        %and if it is chosen to plot


                        %% selected data for plot
                        %sensor 1
                        app.p_index = [];
                        if app.s1_d_CheckBox.Value
                            if app.ori_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    % alternatively horzcat
                                    app.p_index(end + 1) = app.i_ori(1,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(1,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(1,3);
                                end
                            end
                            if app.gyro_d_CheckBox.Value
                                if app.x_d_CheckBox.Value %app.d_x
                                    app.p_index(end+1) = app.i_gyro(1,1);
                                end
                                if app.y_d_CheckBox.Value %app.d_y
                                    app.p_index(end+1) = app.i_gyro(1,2);
                                end
                                if app.z_d_CheckBox.Value %app.d_z
                                    app.p_index(end+1) = app.i_gyro(1,3);
                                end
                            end
                            if app.linacc_d_CheckBox.Value %app.d_linacc
                                if app.x_d_CheckBox.Value  %app.d_x
                                    app.p_index(end+1) = app.i_linacc(1,1);
                                end
                                if app.y_d_CheckBox.Value %app.d_y
                                    app.p_index(end+1) = app.i_linacc(1,2);
                                end
                                if app.z_d_CheckBox.Value %app.d_z
                                    app.p_index(end+1) = app.i_linacc(1,3);
                                end
                            end
                            if app.mag_d_CheckBox.Value %app.d_mag
                                if app.x_d_CheckBox.Value %app.d_x
                                    app.p_index(end+1) = app.i_mag(1,1);
                                end
                                if app.y_d_CheckBox.Value %app.d_y
                                    app.p_index(end+1) = app.i_mag(1,2);
                                end
                                if app.z_d_CheckBox.Value %app.d_z
                                    app.p_index(end+1) = app.i_mag(1,3);
                                end
                            end
                            if app.acc_d_CheckBox.Value %app.d_acc
                                if app.x_d_CheckBox.Value %app.d_x
                                    app.p_index(end+1) = app.i_acc(1,1);
                                end
                                if app.y_d_CheckBox.Value %app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_acc(1,2);
                                end
                                if app.z_d_CheckBox.Value %app.d_z
                                    app.p_index(end+1) = app.i_acc(1,3);
                                end
                            end
                            if app.grav_d_CheckBox.Value %app.d_grav
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(1,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(1,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(1,3);
                                end
                            end
                        end

                        if app.s2_d_CheckBox.Value
                            if app.ori_d_CheckBox.Value %app.d_ori
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(2,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(2,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(2,3);
                                end
                            end
                            if app.gyro_d_CheckBox.Value %app.gyro_d_CheckBox
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_gyro(2,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_gyro(2,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_gyro(2,3);
                                end
                            end
                            if app.linacc_d_CheckBox.Value %app.d_linacc
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_linacc(2,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_linacc(2,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_linacc(2,3);
                                end
                            end
                            if  app.mag_d_CheckBox.Value %app.d_mag
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_mag(2,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_mag(2,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_mag(2,3);
                                end
                            end
                            if app.acc_d_CheckBox.Value %app.d_acc
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_acc(2,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_acc(2,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_acc(2,3);
                                end
                            end
                            if app.grav_d_CheckBox.Value %app.d_grav
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(2,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(2,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(2,3);
                                end
                            end
                        end

                        if app.s3_d_CheckBox.Value
                            if app.ori_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(3,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(3,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(3,3);
                                end
                            end
                            if app.gyro_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_gyro(3,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_gyro(3,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_gyro(3,3);
                                end
                            end
                            if app.linacc_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_linacc(3,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_linacc(3,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_linacc(3,3);
                                end
                            end
                            if app.mag_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_mag(3,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_mag(3,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_mag(3,3);
                                end
                            end
                            if app.acc_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_acc(3,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_acc(3,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_acc(3,3);
                                end
                            end
                            if app.grav_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(3,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(3,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(3,3);
                                end
                            end
                        end

                        if app.s4_d_CheckBox.Value
                            if app.ori_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(4,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(4,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_ori(4,3);
                                end
                            end
                            if app.gyro_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_gyro(4,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_gyro(4,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_gyro(4,3);
                                end
                            end
                            if app.linacc_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_linacc(4,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_linacc(4,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_linacc(4,3);
                                end
                            end
                            if app.mag_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_mag(4,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_mag(4,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_mag(4,3);
                                end
                            end
                            if app.acc_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_acc(4,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_acc(4,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_acc(4,3);
                                end
                            end
                            if app.grav_d_CheckBox.Value
                                if app.x_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(4,1);
                                end
                                if app.y_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(4,2);
                                end
                                if app.z_d_CheckBox.Value
                                    app.p_index(end+1) = app.i_grav(4,3);
                                end
                            end
                        end


                        %adding values to data array
                        if app.plot_counter_index ~= 1
                            %checking if both arrays are the same size
                            if size(app.buff,2) == size(app.r_data,2)
                                %adding value to data array
                                app.r_data(app.plot_counter_index,:) = app.buff;
                            else
                                app.plot_counter_index = app.plot_counter_index - 1;
                            end
                        else
                            %adding value to data array and setting size
                            app.r_data(app.plot_counter_index,:) = app.buff;
                        end


                        %update x limit vaule
                        app.plot_xlimit = app.xrangeEditField.Value;


                        %plot data
                        %while plot data is shorter than plot xlimit
                        if app.plot_counter_index <= app.plot_xlimit
                            plot(app.UIAxes_up,   app.r_data)

                            plot(app.UIAxes_down, app.r_data(:, app.p_index))
                        else
                            %when plot data is longer; plot moves to the left
                            plot(app.UIAxes_up,     app.r_data(end - app.plot_xlimit:end, :))
                            
                            plot(app.UIAxes_down,   app.r_data(end - app.plot_xlimit:end, app.p_index))
                        end

                        app.UIAxes_up.XLim   = [1 app.plot_xlimit];
                        app.UIAxes_down.XLim = [1 app.plot_xlimit];

                        drawnow
                        app.plot_counter_index = app.plot_counter_index + 1;
                        toc
                    end
                end
            end
            
        end

        % Callback function
        function disconnectButtonValueChanged(app, event)

        end

        % Value changed function: ori_s_CheckBox
        function ori_s_CheckBoxValueChanged(app, event)
            app.s_ori = app.ori_s_CheckBox.Value;
            % set display pannel variables 
            app.ori_d_CheckBox.Value = app.ori_s_CheckBox.Value; 
        end

        % Value changed function: gyro_s_CheckBox
        function gyro_s_CheckBoxValueChanged(app, event)
            app.s_gyro = app.gyro_s_CheckBox.Value;
            % set display pannel variables
            app.gyro_d_CheckBox.Value = app.gyro_s_CheckBox.Value; 
        end

        % Value changed function: linacc_s_CheckBox
        function linacc_s_CheckBoxValueChanged(app, event)
            app.s_linacc = app.linacc_s_CheckBox.Value;
            % set display pannel variables
            app.linacc_d_CheckBox.Value = app.linacc_s_CheckBox.Value;
        end

        % Value changed function: mag_s_CheckBox
        function mag_s_CheckBoxValueChanged(app, event)
            app.s_mag = app.mag_s_CheckBox.Value;
            % set display pannel variables
            app.mag_d_CheckBox.Value = app.mag_s_CheckBox.Value;
        end

        % Value changed function: acc_s_CheckBox
        function acc_s_CheckBoxValueChanged(app, event)
            app.s_acc = app.acc_s_CheckBox.Value;
            % set display pannel variables
            app.acc_d_CheckBox.Value = app.acc_s_CheckBox.Value;
        end

        % Value changed function: grav_s_CheckBox
        function grav_s_CheckBoxValueChanged(app, event)
            app.s_grav = app.grav_s_CheckBox.Value;
            % set display pannel variables
            app.grav_d_CheckBox.Value = app.grav_s_CheckBox.Value;
        end

        % Value changed function: s1_s_CheckBox
        function s1_s_CheckBoxValueChanged(app, event)
            app.s_sensor1 = app.s1_s_CheckBox.Value;
            % set display pannel variables 
            app.s1_d_CheckBox.Value = app.s1_s_CheckBox.Value;
            
 
        end

        % Value changed function: s2_s_CheckBox
        function s2_s_CheckBoxValueChanged(app, event)
            app.s_sensor2 = app.s2_s_CheckBox.Value;
            % set display pannel variables 
            app.s2_d_CheckBox.Value = app.s2_s_CheckBox.Value;
            

        end

        % Value changed function: s3_s_CheckBox
        function s3_s_CheckBoxValueChanged(app, event)
            app.s_sensor3 = app.s3_s_CheckBox.Value;
            % set display pannel variables 
            app.s3_d_CheckBox.Value = app.s3_s_CheckBox.Value;


        end

        % Value changed function: s4_s_CheckBox
        function s4_s_CheckBoxValueChanged(app, event)
            app.s_sensor4 = app.s4_s_CheckBox.Value;
            % set display pannel variables 
            app.s4_d_CheckBox.Value = app.s4_s_CheckBox.Value;


        end

        % Value changed function: ori_d_CheckBox
        function ori_d_CheckBoxValueChanged(app, event)
            if app.ori_s_CheckBox.Value
                app.d_ori = app.ori_d_CheckBox.Value;
            else
                app.ori_d_CheckBox.Value = 0;
            end
        end

        % Button pushed function: disconnectButton
        function disconnectButtonPushed(app, event)
            % connectin to device
            app.ble_device = "";                                            % if there is a connection disconnect
            %app.ble_device = ble("FOG Nano");                               % connect to ble device
            %app.ble_c = characteristic(app.ble_device, "180F", "2A19");     % get characteristics

            % setting draw_plot
            app.draw_plot = false;                                           % bool to start the plot loop
            app.p_sensor_counter = 0;                                          %reseting sensor counter variable
            app.Lamp.Color = 'red';
        end

        % Value changed function: s4_d_CheckBox
        function s4_d_CheckBoxValueChanged(app, event)
            if app.s4_s_CheckBox.Value
                app.d_sensor4 = app.s4_d_CheckBox.Value;
            else
                app.s4_d_CheckBox.Value = 0;
            end
        end

        % Value changed function: s3_d_CheckBox
        function s3_d_CheckBoxValueChanged(app, event)
            if app.s3_s_CheckBox.Value
                app.d_sensor3 = app.s3_d_CheckBox.Value;
            else
                app.s3_d_CheckBox.Value = 0;
            end
        end

        % Value changed function: s2_d_CheckBox
        function s2_d_CheckBoxValueChanged(app, event)
            if app.s2_s_CheckBox.Value
                app.d_sensor2 = app.s2_d_CheckBox.Value;
            else
                app.s2_d_CheckBox.Value = 0;
            end
        end

        % Value changed function: s1_d_CheckBox
        function s1_d_CheckBoxValueChanged(app, event)
            if app.s1_s_CheckBox.Value
                app.d_sensor1 = app.s1_d_CheckBox.Value;
            else
                app.s1_d_CheckBox.Value = 0;
            end
        end

        % Value changed function: gyro_d_CheckBox
        function gyro_d_CheckBoxValueChanged(app, event)
            if app.gyro_s_CheckBox.Value
                app.d_gyro = app.gyro_d_CheckBox.Value;
            else
                app.gyro_d_CheckBox.Value = 0;
            end
        end

        % Value changed function: linacc_d_CheckBox
        function linacc_d_CheckBoxValueChanged(app, event)
            if app.linacc_s_CheckBox.Value
                app.d_linacc = app.linacc_d_CheckBox.Value;
            else
                app.linacc_d_CheckBox.Value = 0;
            end
        end

        % Value changed function: mag_d_CheckBox
        function mag_d_CheckBoxValueChanged(app, event)
            if app.mag_s_CheckBox.Value
                app.d_mag = app.mag_d_CheckBox.Value;
            else
                app.mag_d_CheckBox.Value = 0;
            end
        end

        % Value changed function: acc_d_CheckBox
        function acc_d_CheckBoxValueChanged(app, event)
            if app.acc_s_CheckBox.Value
                app.d_acc = app.acc_d_CheckBox.Value;
            else
                app.acc_d_CheckBox.Value = 0;
            end
        end

        % Value changed function: grav_d_CheckBox
        function grav_d_CheckBoxValueChanged(app, event)
            if app.grav_s_CheckBox.Value
                app.d_grav = app.grav_d_CheckBox.Value;
            else
                app.grav_d_CheckBox.Value = 0;
            end
        end

        % Value changed function: x_d_CheckBox
        function x_d_CheckBoxValueChanged(app, event)
            app.d_x = app.x_d_CheckBox.Value;
            
        end

        % Value changed function: y_d_CheckBox
        function y_d_CheckBoxValueChanged(app, event)
            app.d_y = app.y_d_CheckBox.Value;
            
        end

        % Value changed function: z_d_CheckBox
        function z_d_CheckBoxValueChanged(app, event)
            app.d_z = app.z_d_CheckBox.Value;
            
        end

        % Value changed function: xrangeEditField
        function xrangeEditFieldValueChanged(app, event)
            app.plot_xlimit = app.xrangeEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {789, 789};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.setuppannelPanel = uipanel(app.LeftPanel);
            app.setuppannelPanel.Title = 'setup pannel';
            app.GridLayout2 = uigridlayout(app.setuppannelPanel);
            app.ori_s_CheckBox = uicheckbox(app.GridLayout2);
            app.ori_s_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ori_s_CheckBoxValueChanged, true);
            app.ori_s_CheckBox.Text = 'orientation';
            app.gyro_s_CheckBox = uicheckbox(app.GridLayout2);
            app.gyro_s_CheckBox.ValueChangedFcn = createCallbackFcn(app, @gyro_s_CheckBoxValueChanged, true);
            app.gyro_s_CheckBox.Text = 'gyroscope';
            app.linacc_s_CheckBox = uicheckbox(app.GridLayout2);
            app.linacc_s_CheckBox.ValueChangedFcn = createCallbackFcn(app, @linacc_s_CheckBoxValueChanged, true);
            app.linacc_s_CheckBox.Text = 'linear acc';
            app.mag_s_CheckBox = uicheckbox(app.GridLayout2);
            app.mag_s_CheckBox.ValueChangedFcn = createCallbackFcn(app, @mag_s_CheckBoxValueChanged, true);
            app.mag_s_CheckBox.Text = 'magetometer';
            app.acc_s_CheckBox = uicheckbox(app.GridLayout2);
            app.acc_s_CheckBox.ValueChangedFcn = createCallbackFcn(app, @acc_s_CheckBoxValueChanged, true);
            app.acc_s_CheckBox.Text = 'acceleration';
            app.grav_s_CheckBox = uicheckbox(app.GridLayout2);
            app.grav_s_CheckBox.ValueChangedFcn = createCallbackFcn(app, @grav_s_CheckBoxValueChanged, true);
            app.grav_s_CheckBox.Text = 'gravitation';
            app.s1_s_CheckBox = uicheckbox(app.GridLayout2);
            app.s1_s_CheckBox.ValueChangedFcn = createCallbackFcn(app, @s1_s_CheckBoxValueChanged, true);
            app.s1_s_CheckBox.Text = 'sensor #1';
            app.s2_s_CheckBox = uicheckbox(app.GridLayout2);
            app.s2_s_CheckBox.ValueChangedFcn = createCallbackFcn(app, @s2_s_CheckBoxValueChanged, true);
            app.s2_s_CheckBox.Text = 'sensor #2';
            app.s3_s_CheckBox = uicheckbox(app.GridLayout2);
            app.s3_s_CheckBox.ValueChangedFcn = createCallbackFcn(app, @s3_s_CheckBoxValueChanged, true);
            app.s3_s_CheckBox.Text = 'sensor #3';
            app.s4_s_CheckBox = uicheckbox(app.GridLayout2);
            app.s4_s_CheckBox.ValueChangedFcn = createCallbackFcn(app, @s4_s_CheckBoxValueChanged, true);
            app.s4_s_CheckBox.Text = 'sensor #4';
            app.connectButton = uibutton(app.LeftPanel, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.displaypannelPanel = uipanel(app.LeftPanel);
            app.displaypannelPanel.Title = 'display pannel';
            app.GridLayout3 = uigridlayout(app.displaypannelPanel);
            app.ori_d_CheckBox = uicheckbox(app.GridLayout3);
            app.ori_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ori_d_CheckBoxValueChanged, true);
            app.ori_d_CheckBox.Text = 'ori';
            app.gyro_d_CheckBox = uicheckbox(app.GridLayout3);
            app.gyro_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @gyro_d_CheckBoxValueChanged, true);
            app.gyro_d_CheckBox.Text = 'gyro';
            app.linacc_d_CheckBox = uicheckbox(app.GridLayout3);
            app.linacc_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @linacc_d_CheckBoxValueChanged, true);
            app.linacc_d_CheckBox.Text = 'linacc';
            app.mag_d_CheckBox = uicheckbox(app.GridLayout3);
            app.mag_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @mag_d_CheckBoxValueChanged, true);
            app.mag_d_CheckBox.Text = 'mag';
            app.acc_d_CheckBox = uicheckbox(app.GridLayout3);
            app.acc_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @acc_d_CheckBoxValueChanged, true);
            app.acc_d_CheckBox.Text = 'acc';
            app.grav_d_CheckBox = uicheckbox(app.GridLayout3);
            app.grav_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @grav_d_CheckBoxValueChanged, true);
            app.grav_d_CheckBox.Text = 'grav';
            app.x_d_CheckBox = uicheckbox(app.GridLayout3);
            app.x_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @x_d_CheckBoxValueChanged, true);
            app.x_d_CheckBox.Text = 'x';
            app.x_d_CheckBox.Value = true;
            app.y_d_CheckBox = uicheckbox(app.GridLayout3);
            app.y_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @y_d_CheckBoxValueChanged, true);
            app.y_d_CheckBox.Text = 'y';
            app.y_d_CheckBox.Value = true;
            app.z_d_CheckBox = uicheckbox(app.GridLayout3);
            app.z_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @z_d_CheckBoxValueChanged, true);
            app.z_d_CheckBox.Text = 'z';
            app.z_d_CheckBox.Value = true;
            app.s1_d_CheckBox = uicheckbox(app.GridLayout3);
            app.s1_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @s1_d_CheckBoxValueChanged, true);
            app.s1_d_CheckBox.Text = 's #1';
            app.s2_d_CheckBox = uicheckbox(app.GridLayout3);
            app.s2_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @s2_d_CheckBoxValueChanged, true);
            app.s2_d_CheckBox.Text = 's #2';
            app.s3_d_CheckBox = uicheckbox(app.GridLayout3);
            app.s3_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @s3_d_CheckBoxValueChanged, true);
            app.s3_d_CheckBox.Text = 's #3';
            app.s4_d_CheckBox = uicheckbox(app.GridLayout3);
            app.s4_d_CheckBox.ValueChangedFcn = createCallbackFcn(app, @s4_d_CheckBoxValueChanged, true);
            app.s4_d_CheckBox.Text = 's #4';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.xrangeEditFieldLabel = uilabel(app.LeftPanel);
            app.xrangeEditFieldLabel.Text = 'x range';
            app.xrangeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.xrangeEditField.Limits = [10 100000];
            app.xrangeEditField.ValueDisplayFormat = '%.0f';
            app.xrangeEditField.ValueChangedFcn = createCallbackFcn(app, @xrangeEditFieldValueChanged, true);
            app.xrangeEditField.Value = 250;
            app.Lamp = uilamp(app.LeftPanel);
            app.disconnectButton = uibutton(app.LeftPanel, 'push');
            app.disconnectButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectButtonPushed, true);
            app.disconnectButton.Text = 'disconnect';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_up = uiaxes(app.RightPanel);
            title(app.UIAxes_up, 'all values from ''setup pannel''')
            xlabel(app.UIAxes_up, 'X')
            ylabel(app.UIAxes_up, 'Y')
            zlabel(app.UIAxes_up, 'Z')
            app.UIAxes_down = uiaxes(app.RightPanel);
            title(app.UIAxes_down, 'choosen values from ''display pannel''')
            xlabel(app.UIAxes_down, 'X')
            ylabel(app.UIAxes_down, 'Y')
            zlabel(app.UIAxes_down, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end